classdef DefeitoRolamento < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DefeitoRolamentoUIFigure  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        ModeloPanel               matlab.ui.container.Panel
        CalcularButton            matlab.ui.control.Button
        FrequnciaamostragemEditFieldLabel  matlab.ui.control.Label
        FrequenciaEditField       matlab.ui.control.NumericEditField
        DuraoEditFieldLabel       matlab.ui.control.Label
        DuracaoEditField          matlab.ui.control.NumericEditField
        ResultadosPanel           matlab.ui.container.Panel
        ComponenteDropDownLabel   matlab.ui.control.Label
        ComponenteDropDown        matlab.ui.control.DropDown
        MedioDropDownLabel        matlab.ui.control.Label
        MedicaoDropDown           matlab.ui.control.DropDown
        ExibirDropDownLabel       matlab.ui.control.Label
        ExibirDropDown            matlab.ui.control.DropDown
        AplicarButton             matlab.ui.control.Button
        MostrarNovaJanela         matlab.ui.container.Panel
        AtualNovaJanelaButton     matlab.ui.control.Button
        TresMedicoesButton        matlab.ui.control.Button
        ForcaImpactoButton        matlab.ui.control.Button
        xmnEditFieldLabel         matlab.ui.control.Label
        xminEditField             matlab.ui.control.NumericEditField
        xmxEditFieldLabel         matlab.ui.control.Label
        xmaxEditField             matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        resultados = []; % Resultados da pista externa, esfera e pista interna
        FImp = []; % Perfil de forca de impacto ao longo do tempo
        t = []; % Vetor tempo
        Fs = 50e3;
    end
    
    methods (Access = private)
        
        % Atualiza a exibicao do plot dentro do app com as opcoes selecionadas.
        function [] = AtualizarPlotApp(app)
            switch app.MedicaoDropDown.Value
                case 'Deslocamento'
                    app.UIAxes.YLabel.String = 'Deslocamento [m]';
                case 'Velocidade'
                    app.UIAxes.YLabel.String = 'Velocidade [m/s]';
                case 'Aceleração'
                    app.UIAxes.YLabel.String = 'Aceleração [m/s^2]';
            end
            app.UIAxes.Title.String = [app.ComponenteDropDown.Value ...
                ' - ' app.ExibirDropDown.Value];
            if strcmp(app.ExibirDropDown.Value,'Perfil no tempo')
                app.UIAxes.XLabel.String = 'Tempo [s]';
            else
                app.UIAxes.XLabel.String = 'Frequência [Hz]';
            end
            [x,y] = ObterValoresPlot(app);
            plot(app.UIAxes,x,y);
            app.UIAxes.XLimMode = 'auto';
            
            app.xminEditField.Value = app.UIAxes.XLim(1);
            app.xmaxEditField.Value = app.UIAxes.XLim(2);
        end
        
        % Seleciona e calcula se necessario os valores para serem plotados conforme
        % opcoes entradas pelo usuario.
        function [x,y] = ObterValoresPlot(app)
            componenteIndex = 1;
            switch app.ComponenteDropDown.Value
                case 'Anel externo'
                    componenteIndex = 1;
                case 'Esfera'
                    componenteIndex = 2;
                case 'Anel interno'
                    componenteIndex = 3;
            end
            yData = [];
            switch app.MedicaoDropDown.Value
                case 'Deslocamento'
                    yData = app.resultados(componenteIndex).pos;
                case 'Velocidade'
                    yData = app.resultados(componenteIndex).vel;
                case 'Aceleração'
                    yData = app.resultados(componenteIndex).acc;
            end
            if strcmp(app.ExibirDropDown.Value,'Perfil no tempo')
                x = app.t;
                y = yData;
            else
                L = length(yData);
                f = (0:L/2)*(app.Fs/L);
                x = f;
                if strcmp(app.ExibirDropDown.Value,'Espectro de amplitude')
                    Y = fft(yData);
                    P2 = abs(Y/L);
                    P1 = P2(1:L/2+1);
                    P1(2:end-1) = 2*P1(2:end-1);
                    y = P1;
                elseif strcmp(app.ExibirDropDown.Value,'Espectro de envelope')
                    y = envspectrum(yData, app.Fs);
                end
            end
        end
        
        function [] = AtualizarLimitesPlot(app)
            app.UIAxes.XLim = [app.xminEditField.Value app.xmaxEditField.Value];
            app.UIAxes.YLimMode = 'auto';
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TresMedicoesButton.Tooltip = {'Exibe o deslocamento, velocidade e aceleração do '...
                'componente selecionado ao longo do tempo, em três plots individuais.'};
            app.AplicarButton.Enable = false;
            app.AtualNovaJanelaButton.Enable = false;
            app.TresMedicoesButton.Enable = false;
            app.ForcaImpactoButton.Enable = false;
            app.UIAxes.Title.String = 'Modelo ainda não calculado';
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            d = uiprogressdlg(app.DefeitoRolamentoUIFigure,'Title','Aguarde',...
                'Message','Calculando o modelo',...
                'Indeterminate','on');
            [app.resultados, app.FImp, app.t] = ...
                FalhaPistaExterna(app.DuracaoEditField.Value, app.Fs);
            d.Message = 'Ajustando exibição';
            app.AtualizarPlotApp();
            app.AplicarButton.Enable = true;
            app.AtualNovaJanelaButton.Enable = true;
            app.TresMedicoesButton.Enable = true;
            app.ForcaImpactoButton.Enable = true;
            close(d);
        end

        % Button pushed function: AtualNovaJanelaButton
        function AtualNovaJanelaButtonPushed(app, event)
            labelY = app.MedicaoDropDown.Value;
            xlimits = 'auto';
            switch app.MedicaoDropDown.Value
                case 'Deslocamento'
                    labelY = [labelY ' [m]'];
                case 'Velocidade'
                    labelY = [labelY ' [m/s]'];
                case 'Aceleração'
                    labelY = [labelY ' [m/s^2]'];
            end
            WindowTitle = [app.ComponenteDropDown.Value ...
                ' - ' app.ExibirDropDown.Value];
            if strcmp(app.ExibirDropDown.Value,'Perfil no tempo')
                labelX = 'Tempo [s]';
                xlimits = [app.xminEditField.Value app.xmaxEditField.Value];
            else
                labelX = 'Frequência [Hz]';
            end
            labels = struct('WindowTitle',WindowTitle,'X',labelX,'Y',labelY);
            [x,y] = ObterValoresPlot(app);
            ExibirPlotUnico(x,y,labels,xlimits);
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            d = uiprogressdlg(app.DefeitoRolamentoUIFigure,...
                'Title','Aguarde',...
                'Message','Desenhando gráfico',...
                'Indeterminate','on');
            AtualizarPlotApp(app);
            close(d);
        end

        % Value changed function: xminEditField
        function xminEditFieldValueChanged(app, event)
            AtualizarLimitesPlot(app);
        end

        % Value changed function: xmaxEditField
        function xmaxEditFieldValueChanged(app, event)
            AtualizarLimitesPlot(app);
        end

        % Button pushed function: ForcaImpactoButton
        function ForcaImpactoButtonPushed(app, event)
            labels = struct('WindowTitle','Perfil da força de impacto',...
                'X','Tempo [s]','Y','Força [N]');
            ExibirPlotUnico(app.t,app.FImp,labels,[0 0.1]);
        end

        % Value changed function: FrequenciaEditField
        function FrequenciaEditFieldValueChanged(app, event)
            value = app.FrequenciaEditField.Value;
            app.Fs = value*1e3;
        end

        % Button pushed function: TresMedicoesButton
        function TresMedicoesButtonPushed(app, event)
            switch app.ComponenteDropDown.Value
                case 'Anel externo'
                    dados = app.resultados(1);
                case 'Esfera'
                    dados = app.resultados(2);
                case 'Anel interno'
                    dados = app.resultados(3);
            end
            y = [dados.pos dados.vel dados.acc];
            labels = struct('WindowTitle',app.ComponenteDropDown.Value,...
                'X','Tempo [s]',...
                'Y1','Deslocamento [m]',...
                'Y2','Velocidade [m/s]',...
                'Y3','Aceleração [m/s^2]');
            xlimits = [app.xminEditField.Value app.xmaxEditField.Value];
            ExibirPlotTriplo(app.t,y,labels,xlimits);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DefeitoRolamentoUIFigure = uifigure;
            app.DefeitoRolamentoUIFigure.Name = 'Modelo de defeito em rolamento';
            app.UIAxes = uiaxes(app.DefeitoRolamentoUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ModeloPanel = uipanel(app.DefeitoRolamentoUIFigure);
            app.ModeloPanel.Title = 'Modelo';
            app.CalcularButton = uibutton(app.ModeloPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Tooltip = {'Executa o cálculo do modelo. Essa operação pode levar vários minutos.'};
            app.CalcularButton.Text = 'Calcular modelo';
            app.FrequnciaamostragemEditFieldLabel = uilabel(app.ModeloPanel);
            app.FrequnciaamostragemEditFieldLabel.Text = {'Frequência'; 'amostragem'};
            app.FrequenciaEditField = uieditfield(app.ModeloPanel, 'numeric');
            app.FrequenciaEditField.Limits = [1 200];
            app.FrequenciaEditField.ValueDisplayFormat = '%11.4g kHz';
            app.FrequenciaEditField.ValueChangedFcn = createCallbackFcn(app, @FrequenciaEditFieldValueChanged, true);
            app.FrequenciaEditField.Tooltip = {'Frequência de amostragem para o sinal de vibração modelado.'};
            app.FrequenciaEditField.Value = 50;
            app.DuraoEditFieldLabel = uilabel(app.ModeloPanel);
            app.DuraoEditFieldLabel.Text = 'Duração';
            app.DuracaoEditField = uieditfield(app.ModeloPanel, 'numeric');
            app.DuracaoEditField.Limits = [0.1 10];
            app.DuracaoEditField.ValueDisplayFormat = '%11.4g s';
            app.DuracaoEditField.Tooltip = {'Duração do sinal de vibração desejado.'};
            app.DuracaoEditField.Value = 4;
            app.ResultadosPanel = uipanel(app.DefeitoRolamentoUIFigure);
            app.ResultadosPanel.Title = 'Resultados';
            app.ComponenteDropDownLabel = uilabel(app.ResultadosPanel);
            app.ComponenteDropDownLabel.Text = 'Componente';
            app.ComponenteDropDown = uidropdown(app.ResultadosPanel);
            app.ComponenteDropDown.Items = {'Anel externo', 'Esfera', 'Anel interno'};
            app.ComponenteDropDown.Value = 'Anel externo';
            app.MedioDropDownLabel = uilabel(app.ResultadosPanel);
            app.MedioDropDownLabel.Text = 'Medição';
            app.MedicaoDropDown = uidropdown(app.ResultadosPanel);
            app.MedicaoDropDown.Items = {'Deslocamento', 'Velocidade', 'Aceleração'};
            app.MedicaoDropDown.Value = 'Deslocamento';
            app.ExibirDropDownLabel = uilabel(app.ResultadosPanel);
            app.ExibirDropDownLabel.Text = 'Exibir';
            app.ExibirDropDown = uidropdown(app.ResultadosPanel);
            app.ExibirDropDown.Items = {'Perfil no tempo', 'Espectro de amplitude', 'Espectro de envelope'};
            app.ExibirDropDown.Value = 'Perfil no tempo';
            app.AplicarButton = uibutton(app.ResultadosPanel, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Tooltip = {'Atualiza o gráfico de acordo com as opções selecionadas.'};
            app.AplicarButton.Text = 'Aplicar';
            app.MostrarNovaJanela = uipanel(app.DefeitoRolamentoUIFigure);
            app.MostrarNovaJanela.Title = 'Mostrar em nova janela';
            app.AtualNovaJanelaButton = uibutton(app.MostrarNovaJanela, 'push');
            app.AtualNovaJanelaButton.ButtonPushedFcn = createCallbackFcn(app, @AtualNovaJanelaButtonPushed, true);
            app.AtualNovaJanelaButton.Tooltip = {'Exibe o gráfico correspondente às configurações atuais em uma nova janela.'};
            app.AtualNovaJanelaButton.Text = 'Gráfico atual';
            app.TresMedicoesButton = uibutton(app.MostrarNovaJanela, 'push');
            app.TresMedicoesButton.ButtonPushedFcn = createCallbackFcn(app, @TresMedicoesButtonPushed, true);
            app.TresMedicoesButton.Tooltip = {'Exibe o deslocamento\'; ' velocidade e aceleração do componente.'};
            app.TresMedicoesButton.Text = 'Três medições do componente';
            app.ForcaImpactoButton = uibutton(app.MostrarNovaJanela, 'push');
            app.ForcaImpactoButton.ButtonPushedFcn = createCallbackFcn(app, @ForcaImpactoButtonPushed, true);
            app.ForcaImpactoButton.Tooltip = {'Exibe a força de impacto ao longo do tempo.'};
            app.ForcaImpactoButton.Text = 'Perfil de força de impacto';
            app.xmnEditFieldLabel = uilabel(app.DefeitoRolamentoUIFigure);
            app.xmnEditFieldLabel.Text = 'x mín';
            app.xminEditField = uieditfield(app.DefeitoRolamentoUIFigure, 'numeric');
            app.xminEditField.ValueChangedFcn = createCallbackFcn(app, @xminEditFieldValueChanged, true);
            app.xminEditField.Tooltip = {'Limite mínimo do eixo horizontal do gráfico.'};
            app.xmxEditFieldLabel = uilabel(app.DefeitoRolamentoUIFigure);
            app.xmxEditFieldLabel.Text = 'x máx';
            app.xmaxEditField = uieditfield(app.DefeitoRolamentoUIFigure, 'numeric');
            app.xmaxEditField.ValueChangedFcn = createCallbackFcn(app, @xmaxEditFieldValueChanged, true);
            app.xmaxEditField.Tooltip = {'Limite máximo do eixo horizontal do gráfico.'};
            app.xmaxEditField.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = DefeitoRolamento

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DefeitoRolamentoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DefeitoRolamentoUIFigure)
        end
    end
end