classdef ExibicaoResultados < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DefeitoRolamentoUIFigure  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        StatusLabel               matlab.ui.control.Label
        ModeloPanel               matlab.ui.container.Panel
        RecalcularButton          matlab.ui.control.Button
        FrequnciaamostragemEditFieldLabel  matlab.ui.control.Label
        FrequenciaEditField       matlab.ui.control.NumericEditField
        DuraoEditFieldLabel       matlab.ui.control.Label
        DuracaoEditField          matlab.ui.control.NumericEditField
        ResultadosPanel           matlab.ui.container.Panel
        ComponenteDropDownLabel   matlab.ui.control.Label
        ComponenteDropDown        matlab.ui.control.DropDown
        MedioDropDownLabel        matlab.ui.control.Label
        MedicaoDropDown           matlab.ui.control.DropDown
        ExibirDropDownLabel       matlab.ui.control.Label
        ExibirDropDown            matlab.ui.control.DropDown
        AplicarButton             matlab.ui.control.Button
        MostrarNovaJanela         matlab.ui.container.Panel
        AtualButton               matlab.ui.control.Button
        TresMedicoesButton        matlab.ui.control.Button
        ForcaImpactoButton        matlab.ui.control.Button
        xmnEditFieldLabel         matlab.ui.control.Label
        xminEditField             matlab.ui.control.NumericEditField
        xmxEditFieldLabel         matlab.ui.control.Label
        xmaxEditField             matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        extRes = struct; % Resultados do anel externo
        esfRes = struct; % Resultados da esfera
        intRes = struct; % Resultados do anel interno
        t = []; % Vetor tempo
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StatusLabel.Text = 'Modelo ainda não calculado.';
        end

        % Button pushed function: RecalcularButton
        function RecalcularButtonPushed(app, event)
            app.StatusLabel.Text = 'Pronto.';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DefeitoRolamentoUIFigure = uifigure;
            app.DefeitoRolamentoUIFigure.Name = 'Modelo de defeito em rolamento';
            app.UIAxes = uiaxes(app.DefeitoRolamentoUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StatusLabel = uilabel(app.DefeitoRolamentoUIFigure);
            app.ModeloPanel = uipanel(app.DefeitoRolamentoUIFigure);
            app.ModeloPanel.Title = 'Modelo';
            app.RecalcularButton = uibutton(app.ModeloPanel, 'push');
            app.RecalcularButton.ButtonPushedFcn = createCallbackFcn(app, @RecalcularButtonPushed, true);
            app.RecalcularButton.Text = 'Recalcular';
            app.FrequnciaamostragemEditFieldLabel = uilabel(app.ModeloPanel);
            app.FrequnciaamostragemEditFieldLabel.Text = {'Frequência'; 'amostragem'};
            app.FrequenciaEditField = uieditfield(app.ModeloPanel, 'numeric');
            app.FrequenciaEditField.Limits = [1 200];
            app.FrequenciaEditField.ValueDisplayFormat = '%11.4g kHz';
            app.FrequenciaEditField.Tooltip = {'Frequência de amostragem para o sinal de vibração modelado.'};
            app.FrequenciaEditField.Value = 1;
            app.DuraoEditFieldLabel = uilabel(app.ModeloPanel);
            app.DuraoEditFieldLabel.Text = 'Duração';
            app.DuracaoEditField = uieditfield(app.ModeloPanel, 'numeric');
            app.DuracaoEditField.Limits = [0.1 10];
            app.DuracaoEditField.ValueDisplayFormat = '%11.4g s';
            app.DuracaoEditField.Tooltip = {'Duração do sinal de vibração desejado.'};
            app.DuracaoEditField.Value = 0.1;
            app.ResultadosPanel = uipanel(app.DefeitoRolamentoUIFigure);
            app.ResultadosPanel.Title = 'Resultados';
            app.ComponenteDropDownLabel = uilabel(app.ResultadosPanel);
            app.ComponenteDropDownLabel.Text = 'Componente';
            app.ComponenteDropDown = uidropdown(app.ResultadosPanel);
            app.ComponenteDropDown.Items = {'Anel externo', 'Esfera', 'Anel interno'};
            app.ComponenteDropDown.Value = 'Anel externo';
            app.MedioDropDownLabel = uilabel(app.ResultadosPanel);
            app.MedioDropDownLabel.Text = 'Medição';
            app.MedicaoDropDown = uidropdown(app.ResultadosPanel);
            app.MedicaoDropDown.Items = {'Deslocamento', 'Velocidade', 'Aceleração'};
            app.MedicaoDropDown.Value = 'Deslocamento';
            app.ExibirDropDownLabel = uilabel(app.ResultadosPanel);
            app.ExibirDropDownLabel.Text = 'Exibir';
            app.ExibirDropDown = uidropdown(app.ResultadosPanel);
            app.ExibirDropDown.Items = {'Perfil no tempo', 'Espectro de amplitude', 'Espectro de envelope'};
            app.ExibirDropDown.Value = 'Perfil no tempo';
            app.AplicarButton = uibutton(app.ResultadosPanel, 'push');
            app.AplicarButton.Text = 'Aplicar';
            app.MostrarNovaJanela = uipanel(app.DefeitoRolamentoUIFigure);
            app.MostrarNovaJanela.Title = 'Mostrar em nova janela';
            app.AtualButton = uibutton(app.MostrarNovaJanela, 'push');
            app.AtualButton.Text = 'Gráfico atual';
            app.TresMedicoesButton = uibutton(app.MostrarNovaJanela, 'push');
            app.TresMedicoesButton.Text = 'Três medições do componente';
            app.ForcaImpactoButton = uibutton(app.MostrarNovaJanela, 'push');
            app.ForcaImpactoButton.Text = 'Perfil de força de impacto';
            app.xmnEditFieldLabel = uilabel(app.DefeitoRolamentoUIFigure);
            app.xmnEditFieldLabel.Text = 'x mín';
            app.xminEditField = uieditfield(app.DefeitoRolamentoUIFigure, 'numeric');
            app.xmxEditFieldLabel = uilabel(app.DefeitoRolamentoUIFigure);
            app.xmxEditFieldLabel.Text = 'x máx';
            app.xmaxEditField = uieditfield(app.DefeitoRolamentoUIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = ExibicaoResultados

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DefeitoRolamentoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DefeitoRolamentoUIFigure)
        end
    end
end